classdef GaitAssessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GaitAssessorLabel  matlab.ui.control.Label
        StartButton        matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

             
            arduino=serialport("/dev/cu.usbserial-14120",921600);
             
            fopen(arduino);
             
            disp('arduino connected successfully')
            x=linspace(1,10^98);
            
            align = fscanf(arduino, '%d');
            disp('reading data...')
            
            for i=1:length(x)
            
	            y(i) = 100*(fscanf(arduino, '%d'));
             
                z(i) = 90*(fscanf(arduino, '%d'));
            
                k(i) = (fscanf(arduino, '%d'));
            
               
            end
            
     
            %plot(app.UIAxes,x,y,'filled',x,z,'filled',x,k,'filled')
            %legend ('Heal strike', 'Toe strike', 'Ankle Angle')
            %disp('done')

            disp('making plot..')
        
            scatter(app.UIAxes,x,y,'filled')
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,x,z,'filled')
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,x,k)
            hold(app.UIAxes,'off')
            axis(app.UIAxes,"auto");
            legend (app.UIAxes,'Heal strike', 'Toe strike', 'Ankle Angle')
            disp('done')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1e+99];
            app.UIAxes.YLim = [0 200];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GaitAssessorLabel = uilabel(app.UIFigure);
            app.GaitAssessorLabel.Text = 'Gait Assessor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaitAssessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end